classdef DTMF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InputDisplayEditFieldLabel  matlab.ui.control.Label
        InputDisplayEditField       matlab.ui.control.EditField
        TabGroup                    matlab.ui.container.TabGroup
        NumPadTab                   matlab.ui.container.Tab
        Button_1                    matlab.ui.control.StateButton
        Button_2                    matlab.ui.control.StateButton
        Button_3                    matlab.ui.control.StateButton
        Button_4                    matlab.ui.control.StateButton
        Button_5                    matlab.ui.control.StateButton
        Button_6                    matlab.ui.control.StateButton
        Button_7                    matlab.ui.control.StateButton
        Button_8                    matlab.ui.control.StateButton
        Button_9                    matlab.ui.control.StateButton
        Button_0                    matlab.ui.control.StateButton
        Button_asterisk             matlab.ui.control.StateButton
        Button_hashtag              matlab.ui.control.StateButton
        AButton                     matlab.ui.control.StateButton
        BButton                     matlab.ui.control.StateButton
        CButton                     matlab.ui.control.StateButton
        DButton                     matlab.ui.control.StateButton
        EncodeTab                   matlab.ui.container.Tab
        EncodeButton                matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        DecodeTab                   matlab.ui.container.Tab
        Encoder_Decode              matlab.ui.control.StateButton
        OutputEditFieldLabel        matlab.ui.control.Label
        OutputEditField             matlab.ui.control.EditField
        DTMFENCODERDECODERLabel     matlab.ui.control.Label
    end

    methods (Access = private)

        % Callback function
        function Button_13ValueChanged(app, event)

            value = app.Button_13.Text;
            if app.Button_13.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Value changing function: InputDisplayEditField
        function InputDisplayEditFieldValueChanging(app, event)
            changingValue = value;
            
        end

        % Value changed function: Button_1
        function Button_1ValueChanged(app, event)

            value = app.Button_1.Text;
            if app.Button_1.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            value = app.Button_2.Text;
            if app.Button_2.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            value = app.Button_3.Text;
            if app.Button_3.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            value = app.Button_4.Text;
            if app.Button_4.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            value = app.Button_5.Text;
            if app.Button_5.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            value = app.Button_6.Text;
            if app.Button_6.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
            value = app.Button_7.Text;
            if app.Button_7.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
            value = app.Button_8.Text;
            if app.Button_8.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            value = app.Button_9.Text;
            if app.Button_9.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Value changed function: Button_0
        function Button_0ValueChanged(app, event)
            value = app.Button_0.Text;
            if app.Button_0.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Value changed function: Button_asterisk
        function Button_asteriskValueChanged(app, event)
            value = app.Button_asterisk.Text;
            if app.Button_asterisk.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Value changed function: Button_hashtag
        function Button_hashtagValueChanged(app, event)
            value = app.Button_hashtag.Text;
            if app.Button_hashtag.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Value changed function: AButton
        function AButtonValueChanged(app, event)
            value = app.AButton.Text;
            if app.AButton.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
            
        end

        % Value changed function: BButton
        function BButtonValueChanged(app, event)
            value = app.BButton.Text;
            if app.BButton.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Value changed function: CButton
        function CButtonValueChanged(app, event)
            value = app.CButton.Text;
            if app.CButton.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Value changed function: DButton
        function DButtonValueChanged(app, event)
            value = app.DButton.Text;
            if app.DButton.Value==1
            app.InputDisplayEditField.Value= [app.InputDisplayEditField.Value value];

            end
            
        end

        % Callback function
        function tempButtonPushed(app, event)

          
        end

        % Button pushed function: EncodeButton
        function EncodeButtonPushed(app, event)
            %disp(app.InputDisplayEditField.Value)
            %pause(0.5)
           wav = zeros(1000,1);
            start = 1;
            for w=1:length(app.InputDisplayEditField.Value)
                pause(0.5)
                %disp(w)
                key =app.InputDisplayEditField.Value(w) ;
                %disp(key)
    switch (key)                  
        case {'1' '2' '3' 'A'}
            f1=697;
        case {'4' '5' '6' 'B'}
            f1=770;
        case {'7' '8' '9'  'C'}
            f1=852;
        case {'*' '0' '#' 'D'}
            f1=941;
            %return;
    end
    switch (key)                     
        case {'1' '4' '7' '*'}
            f2=1209;
        case {'2' '5' '8' '0'}
            f2=1336;
        case {'3' '6' '9' '#'}
            f2=1477;
        case { 'A' 'B' 'C' 'D'}
            f2=1633;
    end
    
    fs = 50000;
    d=0.1;
    n = fs*d;
    t = (1:n)/fs;
   % disp(length(t))
    y = 0.3*sin(2*pi*f1*t)+0.3*sin(2*pi*f2*t);
    
    sound(y,fs);
    filename = 'sound.wav';
    audiowrite(filename,y,fs)
    
    %disp(start)
    t2 = (1:n/length(app.InputDisplayEditField.Value))/(50000);
    wav1 = 0.3*sin(2*pi*f1*t2)+0.3*sin(2*pi*f2*t2);
    %wav1
    %disp(length(wav1))
    %disp(length(t2))
    %disp(length(0.3*sin(2*pi*f1*t2)+0.3*sin(2*pi*f2*t2)));
    %disp(length( w*5000/length(app.InputDisplayEditField.Value)));
    l=1;
    for k = start : w*5000/length(app.InputDisplayEditField.Value)%t/length(app.InputDisplayEditField.Value)
        k
        wav(k) = wav1(l);
        l=l+1;
        %l
        %t2 = (1:n)/(50000*length(app.InputDisplayEditField.Value));
       % wav(k) = 0.3*sin(2*pi*f1*t2)+0.3*sin(2*pi*f2*t2);
    end
    start = start + k + 5000;
    %disp(start)
    %wav
    %disp(length(wav))
    
    
    [a,fn] = audioread ('sound.wav');
t = linspace(0,length(a)/fn,length(a));
%hold on
plot(app.UIAxes,t,a);
axis([0 0.1 -1.5 1.5])
%subplot(211)
%plot(t,a)
%subplot(212)
%plot(t,a)
%axis([0 0.1 -1.5 1.5])
%hold off

pause(0.5)
            end
         %   disp(length(wav1))
   % disp(length(wav))
    %disp(wav(5000))
    %disp(length(y)) 
    %filename2='sound2.wav';
    %audiowrite(filename2,wav,fs)
    %[a2,fn2] = audioread ('sound.wav');
    %t = linspace(0,length(a2)/fn2,length(a2));
    %plot(t,a2);
    %axis([0 0.1 -1.5 1.5])


        end

        % Callback function
        function DecoderButtonPushed(app, event)


[data, freq]=audioread('sound.wav');
[a,fn] = audioread ('sound.wav');
t = linspace(0,length(a)/fn,length(a));
subplot(3,1,1);
plot(t,a);
xlabel('t(s)')


q = audioread ('sound.wav');
sound(q);

[y,Fs] = audioread('sound.wav');
n = length(y);
p = abs(fft(y));
f = (Fs/n)*(0:n-1);

plot(app.UIAxes2,f,p);
app.UIAxes2.XLim = [500 1700];
app.UIAxes2.YLim = [0 1000];
axis([500 1700 0 1000])

subplot(3,1,2);
plot(f,p);
axis([500 1700 0 1000])
xlabel('f(Hz)')


sample = 0.05; 
t = linspace(0,sample,sample*freq); 
width = length(t); 
height = length(data)/width; 
dataS = reshape(data(1:height*width),width,height);

ncol = size(dataS,2);
fr = [697 770 852 941 1209 1336 1477 1633];
KEYS=[];

for i=1:4:ncol
    y = [dataS(:, i)',dataS(:, i+1)'];
    n = length(y);
    freq_indices = round(fr/freq*n) + 1;   
    dft_data = goertzel(y,freq_indices);
    t=abs(dft_data);
   
    stem(app.UIAxes3,fr,t);
    app.UIAxes3.XLim = [500 1700];
    app.UIAxes3.YLim = [0 1500];
    axis([500 1700 0 1000])


    subplot(3,1,3) 
   stem(fr,t);
   axis([500 1700 0 1500])


    [~,ind] = sort(t,'descend');
    an=fr(ind(1:2));
    [val,~] = sort(an,'descend');
    %KaEYS=[KEYS,detect(val)];
    
    %function c=detect(val)
    hifreq=val(1);
    lofreq=val(2);
    
    if lofreq==697
        if hifreq==1209
            c='1';
        end
        
        if hifreq==1336
            c='2';
        end
        
        if hifreq==1477
            c='3';
        end
        
        if hifreq==1633
            c='A';
        end   
    end
    
    
    if lofreq==770
        if hifreq==1209
            c='4';
        end
        
        if hifreq==1336
            c='5';
        end
        
        if hifreq==1477
            c='6';
        end
        
        if hifreq==1633
            c='B';
        end   
    end
                
    if lofreq==852
        if hifreq==1209
            c='7';
        end
        
        if hifreq==1336
            c='8';
        end
        
        if hifreq==1477
            c='9';
        end
        
        if hifreq==1633
            c='C';
        end   
    end
    
    if lofreq==941
        if hifreq==1209
            c='*';
        end
        
        if hifreq==1336
            c='0';
        end
        
        if hifreq==1477
            c='#';
        end
        
        if hifreq==1633
            c='D';
        end   
    end
end

disp(val)
disp(c)
%disp(KaEYS);

        end

        % Value changed function: Encoder_Decode
        function Encoder_DecodeValueChanged(app, event)
            disp(app.InputDisplayEditField.Value)
            out=zeros(length(app.InputDisplayEditField.Value),1);
            l = length(app.InputDisplayEditField.Value);
            k=1;
            for w=1:length(app.InputDisplayEditField.Value)
                disp(w)
                      key =app.InputDisplayEditField.Value(w) ;
                      disp(key)
    switch (key)                  
        case {'1' '2' '3' 'A'}
            f1=697;
        case {'4' '5' '6' 'B'}
            f1=770;
        case {'7' '8' '9'  'C'}
            f1=852;
        case {'*' '0' '#' 'D'}
            f1=941;
            %return;
    end
    switch (key)                     
        case {'1' '4' '7' '*'}
            f2=1209;
        case {'2' '5' '8' '0'}
            f2=1336;
        case {'3' '6' '9' '#'}
            f2=1477;
        case { 'A' 'B' 'C' 'D'}
            f2=1633;
    end
    
    fs = 50000;
    d=0.1;
    n = fs*d;
    t = (1:n)/fs;
    y = 0.3*sin(2*pi*f1*t)+0.3*sin(2*pi*f2*t);
    sound(y,fs);
    filename = 'sound.wav';
    audiowrite(filename,y,fs)
    
    
    [a,fn] = audioread ('sound.wav');
t = linspace(0,length(a)/fn,length(a));
%hold on
%subplot(211)
%plot(t,a)
%subplot(212)
%plot(t,a)
%axis([0 0.1 -1.5 1.5])
%hold off


[data, freq]=audioread('sound.wav');
[a,fn] = audioread ('sound.wav');
t = linspace(0,length(a)/fn,length(a));
disp(k)
subplot(l,3,k);
plot(t,a)
axis([0 0.05 -1.5 1.5])
xlabel('t(s)')


q = audioread ('sound.wav');
%sound(q);

[y,Fs] = audioread('sound.wav');
n = length(y);
p = abs(fft(y));
f = (Fs/n)*(0:n-1);
subplot(l,3,k+1);
plot(f,p);
axis([500 1700 0 1000])
xlabel('f(Hz)')


sample = 0.05; 
t = linspace(0,sample,sample*freq); 
width = length(t); 
height = length(data)/width; 
dataS = reshape(data(1:height*width),width,height);

ncol = size(dataS,2);
fr = [697 770 852 941 1209 1336 1477 1633];
KEYS=[];

for i=1:4:ncol
    y = [dataS(:, i)',dataS(:, i+1)'];
    n = length(y);
    freq_indices = round(fr/freq*n) + 1;   
    dft_data = goertzel(y,freq_indices);
    t=abs(dft_data);
   subplot(l,3,k+2) 
   stem(fr,t);
   axis([500 1700 0 1500])
   xlabel('f(Hz)')


    [~,ind] = sort(t,'descend');
    an=fr(ind(1:2));
    [val,~] = sort(an,'descend');
    %KaEYS=[KEYS,detect(val)];
    
    %function c=detect(val)
    hifreq=val(1);
    lofreq=val(2);
    
    if lofreq==697
        if hifreq==1209
            c='1';
        end
        
        if hifreq==1336
            c='2';
        end
        
        if hifreq==1477
            c='3';
        end
        
        if hifreq==1633
            c='A';
        end   
    end
    
    
    if lofreq==770
        if hifreq==1209
            c='4';
        end
        
        if hifreq==1336
            c='5';
        end
        
        if hifreq==1477
            c='6';
        end
        
        if hifreq==1633
            c='B';
        end   
    end
                
    if lofreq==852
        if hifreq==1209
            c='7';
        end
        
        if hifreq==1336
            c='8';
        end
        
        if hifreq==1477
            c='9';
        end
        
        if hifreq==1633
            c='C';
        end   
    end
    
    if lofreq==941
        if hifreq==1209
            c='*';
        end
        
        if hifreq==1336
            c='0';
        end
        
        if hifreq==1477
            c='#';
        end
        
        if hifreq==1633
            c='D';
        end 
    end
    out(w)=c;
end
k = k + 3;
pause(10e-2)
app.OutputEditField.Value=mat2str(char(out));

%disp(k)
%disp(val)
%disp(c)
%disp(char(out))

%disp(KaEYS);
%if mat2str(char(c)) == '1'
 %   app.Lamp_2.Color=[1 0 0];
%end


            end
            
        end

        % Value changed function: InputDisplayEditField
        function InputDisplayEditFieldValueChanged(app, event)
            value = app.InputDisplayEditField.Value;
            
        end

        % Callback function
        function Button_10Pushed(app, event)
            app.InputDisplayEditField.Value = default();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.InputDisplayEditFieldLabel = uilabel(app.UIFigure);
            app.InputDisplayEditFieldLabel.Text = 'Input Display';
            app.InputDisplayEditField = uieditfield(app.UIFigure, 'text');
            app.InputDisplayEditField.ValueChangedFcn = createCallbackFcn(app, @InputDisplayEditFieldValueChanged, true);
            app.InputDisplayEditField.ValueChangingFcn = createCallbackFcn(app, @InputDisplayEditFieldValueChanging, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NumPadTab = uitab(app.TabGroup);
            app.NumPadTab.Title = 'NumPad';
            app.Button_1 = uibutton(app.NumPadTab, 'state');
            app.Button_1.ValueChangedFcn = createCallbackFcn(app, @Button_1ValueChanged, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.NumPadTab, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.NumPadTab, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.NumPadTab, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.NumPadTab, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.NumPadTab, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.NumPadTab, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.NumPadTab, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.NumPadTab, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Text = '9';
            app.Button_0 = uibutton(app.NumPadTab, 'state');
            app.Button_0.ValueChangedFcn = createCallbackFcn(app, @Button_0ValueChanged, true);
            app.Button_0.Text = '0';
            app.Button_asterisk = uibutton(app.NumPadTab, 'state');
            app.Button_asterisk.ValueChangedFcn = createCallbackFcn(app, @Button_asteriskValueChanged, true);
            app.Button_asterisk.Text = '*';
            app.Button_hashtag = uibutton(app.NumPadTab, 'state');
            app.Button_hashtag.ValueChangedFcn = createCallbackFcn(app, @Button_hashtagValueChanged, true);
            app.Button_hashtag.Text = '#';
            app.AButton = uibutton(app.NumPadTab, 'state');
            app.AButton.ValueChangedFcn = createCallbackFcn(app, @AButtonValueChanged, true);
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.NumPadTab, 'state');
            app.BButton.ValueChangedFcn = createCallbackFcn(app, @BButtonValueChanged, true);
            app.BButton.Text = 'B';
            app.CButton = uibutton(app.NumPadTab, 'state');
            app.CButton.ValueChangedFcn = createCallbackFcn(app, @CButtonValueChanged, true);
            app.CButton.Text = 'C';
            app.DButton = uibutton(app.NumPadTab, 'state');
            app.DButton.ValueChangedFcn = createCallbackFcn(app, @DButtonValueChanged, true);
            app.DButton.Text = 'D';
            app.EncodeTab = uitab(app.TabGroup);
            app.EncodeTab.Title = 'Encode';
            app.EncodeButton = uibutton(app.EncodeTab, 'push');
            app.EncodeButton.ButtonPushedFcn = createCallbackFcn(app, @EncodeButtonPushed, true);
            app.EncodeButton.Text = 'Encode';
            app.UIAxes = uiaxes(app.EncodeTab);
            title(app.UIAxes, 'Encoded Sound Wave')
            xlabel(app.UIAxes, 'time(s)')
            ylabel(app.UIAxes, 'Amplitude')
            app.DecodeTab = uitab(app.TabGroup);
            app.DecodeTab.Title = 'Decode';
            app.Encoder_Decode = uibutton(app.DecodeTab, 'state');
            app.Encoder_Decode.ValueChangedFcn = createCallbackFcn(app, @Encoder_DecodeValueChanged, true);
            app.Encoder_Decode.Text = 'Decode';
            app.OutputEditFieldLabel = uilabel(app.DecodeTab);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.DecodeTab, 'text');
            app.DTMFENCODERDECODERLabel = uilabel(app.UIFigure);
            app.DTMFENCODERDECODERLabel.Text = 'DTMF ENCODER/DECODER';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DTMF

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end